classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        Label_3           matlab.ui.control.Label
        EditField_3       matlab.ui.control.EditField
        Button            matlab.ui.control.Button
        Label_4           matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        EditField_4       matlab.ui.control.EditField
        EditField_5Label  matlab.ui.control.Label
        EditField_5       matlab.ui.control.EditField
        RedfunctionBlacktaylorfunctionLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            syms x;
            app.UIAxes.cla;
            y = app.EditField_3.Value;
            point = app.EditField_4.Value;
            count = app.EditField_5.Value;
            point1 = str2double(point);
            count1 = str2double(count);
            fplot(app.UIAxes, y, "Color", "Red");
            hold(app.UIAxes,'on');
            grid(app.UIAxes,'on');
            y1 = str2sym(y);
            Taylory = taylor(y1,x, point1,'Order',count1); 
            fplot(app.UIAxes, Taylory, "Color", "Black");
            app.Label_4.Text = char(Taylory);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Axes'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'Функция'; ''};
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычислить';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Точка';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = 'Кол-во';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.RedfunctionBlacktaylorfunctionLabel = uilabel(app.UIFigure);
            app.RedfunctionBlacktaylorfunctionLabel.Text = {'Red - function'; 'Black - taylor function'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end